classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SpikeDetectionLabel       matlab.ui.control.Label
        ChannelSelectSwitch       matlab.ui.control.Switch
        ChannelSelectSwitchLabel  matlab.ui.control.Label
        PlotTypeDropDown          matlab.ui.control.DropDown
        PlotTypeDropDownLabel     matlab.ui.control.Label
        BinSizemsSlider           matlab.ui.control.Slider
        BinSizemsSliderLabel      matlab.ui.control.Label
        PrePlot                   matlab.ui.control.UIAxes
        StimPlot                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stim1_pks 
        stim1_pk_ind
        stim2_pks
        stim2_pk_ind
        pre1_pks
        pre1_pk_ind
        pre2_pks
        pre2_pk_ind
        time
        stim_1_filtered
        stim_2_filtered
        pre_1_filtered
        pre_2_filtered
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.time = transpose(.001:.001:1800);
            [app.stim1_pks,app.stim1_pk_ind,app.stim2_pks,app.stim2_pk_ind,app.pre1_pks,app.pre1_pk_ind,app.pre2_pks,app.pre2_pk_ind,app.stim_1_filtered,app.stim_2_filtered,app.pre_1_filtered,app.pre_2_filtered ] = start_up();

        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            plot_type = app.PlotTypeDropDown.Value; %Get drop down val
            channel = app.ChannelSelectSwitch.Value; %get channel value
            binsize = app.BinSizemsSlider.Value; %get bin size
            binsize = round(binsize);
            %Set plots
            if (plot_type == "Histogram")
                if (channel == "1")
                    histogram(app.PrePlot, app.pre1_pk_ind, round(1800000/binsize));
                    histogram(app.StimPlot, app.stim1_pk_ind, round(1800000/binsize));
                    ylim(app.PrePlot, [0 15]);
                    ylim(app.StimPlot, [0 15]);
                elseif (channel == "2")
                     histogram(app.PrePlot, app.pre2_pk_ind, round(1800000/binsize));
                     histogram(app.StimPlot, app.stim2_pk_ind, round(1800000/binsize));
                     ylim(app.PrePlot, [0 15]);
                     ylim(app.StimPlot, [0 15]);
                end
            elseif (plot_type == "Filtered Data")
                if (channel == "1")
                    plot(app.PrePlot, app.time, app.pre_1_filtered);
                    plot(app.StimPlot, app.time, app.stim_1_filtered);
                    ylim(app.PrePlot, [-2 2]);
                    ylim(app.StimPlot, [-2 2]);
                elseif (channel == "2")
                    plot(app.PrePlot, app.time, app.pre_2_filtered);
                    plot(app.StimPlot, app.time, app.stim_2_filtered);
                    ylim(app.PrePlot, [-2 2]);
                    ylim(app.StimPlot, [-2 2]);
                end
            end
        end

        % Value changed function: ChannelSelectSwitch
        function ChannelSelectSwitchValueChanged(app, event)
            channel = app.ChannelSelectSwitch.Value;
            plot_type = app.PlotTypeDropDown.Value; %Get drop down val
            binsize = app.BinSizemsSlider.Value; %get bin size
            binsize = round(binsize);
            %Set plots
            if (plot_type == "Histogram")
                if (channel == "1")
                    histogram(app.PrePlot, app.pre1_pk_ind, round(1800000/binsize));
                    histogram(app.StimPlot, app.stim1_pk_ind, round(1800000/binsize));
                    ylim(app.PrePlot, [0 15]);
                    ylim(app.StimPlot, [0 15]);
                elseif (channel == "2")
                     histogram(app.PrePlot, app.pre2_pk_ind, round(1800000/binsize));
                     histogram(app.StimPlot, app.stim2_pk_ind, round(1800000/binsize));
                     ylim(app.PrePlot, [0 15]);
                     ylim(app.StimPlot, [0 15]);
                end
            elseif (plot_type == "Filtered Data")
                if (channel == "1")
                    plot(app.PrePlot, app.time, app.pre_1_filtered);
                    plot(app.StimPlot, app.time, app.stim_1_filtered);
                    ylim(app.PrePlot, [-2 2]);
                    ylim(app.StimPlot, [-2 2]);
                elseif (channel == "2")
                    plot(app.PrePlot, app.time, app.pre_2_filtered);
                    plot(app.StimPlot, app.time, app.stim_2_filtered);
                    ylim(app.PrePlot, [-2 2]);
                    ylim(app.StimPlot, [-2 2]);
                end
            end
        end

        % Value changed function: BinSizemsSlider
        function BinSizemsSliderValueChanged(app, event)
            channel = app.ChannelSelectSwitch.Value;
            plot_type = app.PlotTypeDropDown.Value; %Get drop down val
            binsize = app.BinSizemsSlider.Value; %get bin size
            binsize = round(binsize);
            %Set plots
            if (plot_type == "Histogram")
                if (channel == "1")
                    histogram(app.PrePlot, app.pre1_pk_ind, round(1800000/binsize));
                    histogram(app.StimPlot, app.stim1_pk_ind, round(1800000/binsize));
                    ylim(app.PrePlot, [0 15]);
                    ylim(app.StimPlot, [0 15]);
                elseif (channel == "2")
                     histogram(app.PrePlot, app.pre2_pk_ind, round(1800000/binsize));
                     histogram(app.StimPlot, app.stim2_pk_ind, round(1800000/binsize));
                     ylim(app.PrePlot, [0 15]);
                     ylim(app.StimPlot, [0 15]);
                end
            elseif (plot_type == "Filtered Data")
                if (channel == "1")
                    plot(app.PrePlot, app.time, app.pre_1_filtered);
                    plot(app.StimPlot, app.time, app.stim_1_filtered);
                    ylim(app.PrePlot, [-2 2]);
                    ylim(app.StimPlot, [-2 2]);
                elseif (channel == "2")
                    plot(app.PrePlot, app.time, app.pre_2_filtered);
                    plot(app.StimPlot, app.time, app.stim_2_filtered);
                    ylim(app.PrePlot, [-2 2]);
                    ylim(app.StimPlot, [-2 2]);
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StimPlot = uiaxes(app.UIFigure);
            title(app.StimPlot, 'Stim Data')
            xlabel(app.StimPlot, 'Time')
            ylabel(app.StimPlot, 'Y')
            zlabel(app.StimPlot, 'Z')
            app.PrePlot = uiaxes(app.UIFigure);
            title(app.PrePlot, 'Pre Data')
            xlabel(app.PrePlot, 'Time')
            ylabel(app.PrePlot, 'Y')
            zlabel(app.PrePlot, 'Z')
            app.BinSizemsSliderLabel = uilabel(app.UIFigure);
            app.BinSizemsSliderLabel.Text = 'Bin Size (ms)';
            app.BinSizemsSlider = uislider(app.UIFigure);
            app.BinSizemsSlider.Limits = [1 2000];
            app.BinSizemsSlider.MajorTicks = [0 200 400 600 800 1000 1200 1400 1600 1800 2000];
            app.BinSizemsSlider.ValueChangedFcn = createCallbackFcn(app, @BinSizemsSliderValueChanged, true);
            app.BinSizemsSlider.MinorTicks = [1 51 101 151 201 251 301 351 401 451 501 551 601 651 701 751 801 851 901 951 1001 1051 1101 1151 1201 1251 1301 1351 1401 1451 1501 1551 1601 1651 1701 1751 1801 1851 1901 1951];
            app.BinSizemsSlider.Value = 1;
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Histogram', 'Filtered Data'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Histogram';
            app.ChannelSelectSwitchLabel = uilabel(app.UIFigure);
            app.ChannelSelectSwitchLabel.Text = 'Channel Select';
            app.ChannelSelectSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChannelSelectSwitch.Items = {'1', '2'};
            app.ChannelSelectSwitch.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectSwitchValueChanged, true);
            app.ChannelSelectSwitch.Value = '1';
            app.SpikeDetectionLabel = uilabel(app.UIFigure);
            app.SpikeDetectionLabel.Text = 'Spike Detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end